classdef Scube < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        main                           matlab.ui.Figure
        Solventpart                    matlab.ui.container.Panel
        SolventDropDownLabel           matlab.ui.control.Label
        Solvent                        matlab.ui.control.DropDown
        Solutepart                     matlab.ui.container.Panel
        MoleculargeometryfilesLabel    matlab.ui.control.Label
        path_gro                       matlab.ui.control.TextArea
        ConcentrationofsolutemMLabel   matlab.ui.control.Label
        conc                           matlab.ui.control.NumericEditField
        Excitationratiostructurallychangedsolutetotalsolute1Label  matlab.ui.control.Label
        exct                           matlab.ui.control.NumericEditField
        MoleculargeometryfilesLabel_2  matlab.ui.control.Label
        path_pro                       matlab.ui.control.TextArea
        draw                           matlab.ui.control.Button
        Getreactant                    matlab.ui.control.Button
        Getproduct                     matlab.ui.control.Button
        FractionLabel                  matlab.ui.control.Label
        fraction_gro                   matlab.ui.control.EditField
        FractionLabel_2                matlab.ui.control.Label
        fraction_pro                   matlab.ui.control.EditField
        ReactantGroundstateLabel       matlab.ui.control.Label
        ProductIntermediateLabel       matlab.ui.control.Label
        Thermalexcitationratiostructuralchangedsolutetotalsolute1Label  matlab.ui.control.Label
        vibcool                        matlab.ui.control.NumericEditField
        Heatingpart                    matlab.ui.container.Panel
        WavelengthofpumplasernmEditFieldLabel  matlab.ui.control.Label
        wave                           matlab.ui.control.NumericEditField
        dSdTpsSwitchLabel              matlab.ui.control.Label
        dt_switch                      matlab.ui.control.ToggleSwitch
        dSdrhonsSwitchLabel            matlab.ui.control.Label
        drho_switch                    matlab.ui.control.ToggleSwitch
        Simulationpart                 matlab.ui.container.Panel
        CollectiontimeshoursLabel      matlab.ui.control.Label
        hour                           matlab.ui.control.NumericEditField
        PhotonspulseLabel              matlab.ui.control.Label
        photons_per_pulse              matlab.ui.control.NumericEditField
        PulserepetitionrateHzLabel     matlab.ui.control.Label
        pulse_repetition               matlab.ui.control.NumericEditField
        DutycycleLabel                 matlab.ui.control.Label
        para                           matlab.ui.control.NumericEditField
        ExposuretimessLabel            matlab.ui.control.Label
        exposure_time                  matlab.ui.control.NumericEditField
        SampletodetectordistancemmLabel  matlab.ui.control.Label
        sample_distance                matlab.ui.control.NumericEditField
        save                           matlab.ui.container.Panel
        work_dir                       matlab.ui.control.EditField
        getdir                         matlab.ui.control.Button
        getdir_check                   matlab.ui.control.CheckBox
        NoisepartPanel                 matlab.ui.container.Panel
        noise_text                     matlab.ui.control.EditField
        get_noise                      matlab.ui.control.Button
        ConfigurationsFilesPanel       matlab.ui.container.Panel
        Conf_files_name                matlab.ui.control.TextArea
        Load                           matlab.ui.control.Button
        Save                           matlab.ui.control.Button
        Simulate                       matlab.ui.control.Button
        ThecageSoluteSolventcrosstermPanel  matlab.ui.container.Panel
        CagefilesnameLabel             matlab.ui.control.Label
        cage_name                      matlab.ui.control.EditField
        CageprocessmethodKnobLabel     matlab.ui.control.Label
        cage_knob                      matlab.ui.control.DiscreteKnob
        get_cage_name                  matlab.ui.control.Button
        Simulate_batch                 matlab.ui.control.Button
    end

    
    properties (Access = public)
        config % Description
        constant % Description
        results % Description
        cal % Description
    end
    
    methods (Access = private)
        
        function readconfig(app,path,file,~)
            if 0==file
                return
            end
            
            app.path_pro.Value='path';
            app.path_gro.Value='path';
            
            file_name=sprintf('%s/%s',path,file);
            ID=fopen(file_name);
            data_temp=textscan(ID,'%s','Delimiter','\n');
            data=data_temp{1};
            
%             numgnd=str2num(data{2});
%             numpro=str2num(data{5});
            
            numgnd=str2double(data{2});
            numpro=str2double(data{5});
            
            for ii=1:numgnd
                app.path_gro.Value{ii}=data{7+ii};
            end
            
            for ii=1:numpro
                app.path_pro.Value{ii}=data{9+numgnd+ii};
            end
            
            app.Solvent.Value=data{12+numgnd+numpro};
            
            app.conc.Value=str2double(data{15+numgnd+numpro});
            concValueChanged(app);
            
            app.exct.Value=str2double(data{18+numgnd+numpro});
            exctValueChanged(app);
            
            app.wave.Value=str2double(data{21+numgnd+numpro});
            
            app.hour.Value=str2double(data{24+numgnd+numpro});
            hourValueChanged(app);
            
            app.photons_per_pulse.Value=str2double(data{27+numgnd+numpro});
            photons_per_pulseValueChanged(app);
            
            app.pulse_repetition.Value=str2double(data{30+numgnd+numpro});
            pulse_repetitionValueChanged(app);
            
            %app.exposure_time.Value=str2double(data{33+numgnd+numpro});
            %exposure_timeValueChanged(app);
            
            app.para.Value=str2double(data{36+numgnd+numpro});
            paraValueChanged(app);
            
            app.noise_text.Value=data{39+numgnd+numpro};
            app.fraction_gro.Value=data{42+numgnd+numpro};
            app.fraction_pro.Value=data{45+numgnd+numpro};
            app.vibcool.Value=str2double(data{48+numgnd+numpro});
            app.work_dir.Value=data{51+numgnd+numpro};
            app.dt_switch.Value=data{54+numgnd+numpro};
            dt_switchValueChanged(app);
            app.drho_switch.Value=data{57+numgnd+numpro};
            drho_switchValueChanged(app);
            app.sample_distance.Value=str2double(data{60+numgnd+numpro});
            sample_distanceValueChanged(app); %% Temp
            app.cage_knob.Value=data{63+numgnd+numpro};
            cage_knobValueChanged(app);
            app.cage_name.Value=data{66+numgnd+numpro};
            
            
            switch nargin
                case 3
                    temp=sprintf('Loading path : %s',file_name);
                    msgbox({'Configuration file loaded successfully' temp},'Scube')
                case 4
            end
        end
        
        function writeconfig(app,time,path)
            
            switch nargin
                case 3
                    name_file=sprintf('%s/Scube_config_%s.dat',path,time);
                case 2
                    [~,~]=mkdir('config');
                    name_file=sprintf('config/Scube_config_%s.dat',time);
            end
            
            ID_file=fopen(name_file,'w');
            
            try
                fprintf(ID_file,'The_number_of_reatants\n');
            catch
                error('the wrong working directory. Check working directory.');
            end
            
            temp=sprintf('%d\n\n',length(app.path_gro.Value));
            fprintf(ID_file,temp);
            
            fprintf(ID_file,'The_number_of_products_intemediates\n');
            temp=sprintf('%d\n\n',length(app.path_pro.Value));
            fprintf(ID_file,temp);
            
            fprintf(ID_file,'Reactants_atomic_coordination_files\n');
            
            for ii=1:length(app.path_gro.Value)
                temp=sprintf('%s\n',app.path_gro.Value{ii});
                fprintf(ID_file,'%s',temp);
            end
            fprintf(ID_file,'\n');
            
            fprintf(ID_file,'Products_intemediates_atomic_coordination_files\n');
            
            for ii=1:length(app.path_pro.Value)
                temp=sprintf('%s\n',app.path_pro.Value{ii});
                fprintf(ID_file,'%s',temp);
            end
            fprintf(ID_file,'\n');
            
            fprintf(ID_file,'Solvent\n');
            temp=sprintf('%s\n\n',app.Solvent.Value);
            fprintf(ID_file,temp);
            
            fprintf(ID_file,'Concentration_of_solutes\n');
            temp=sprintf('%.4g\n\n',app.conc.Value);
            fprintf(ID_file,temp);
            
            fprintf(ID_file,'Excitation_ratio_structural_changes\n');
            temp=sprintf('%.4g\n\n',app.exct.Value);
            fprintf(ID_file,temp);
            
            fprintf(ID_file,'Wavelength_of_pump_laser\n');
            temp=sprintf('%.4g\n\n',app.wave.Value);
            fprintf(ID_file,temp);
            
            fprintf(ID_file,'Collection_times(hours)\n');
            temp=sprintf('%.4g\n\n',app.hour.Value);
            fprintf(ID_file,temp);
            
            fprintf(ID_file,'Photons/photons_per_pulse\n');
            temp=sprintf('%d\n\n',app.photons_per_pulse.Value);
            fprintf(ID_file,temp);
            
            fprintf(ID_file,'Repetition_Rate(Hz)\n');
            temp=sprintf('%d\n\n',app.pulse_repetition.Value);
            fprintf(ID_file,temp);
            
            fprintf(ID_file,'Exposure_time\n');
            temp=sprintf('%d\n\n',app.exposure_time.Value);
            fprintf(ID_file,temp);
            
            fprintf(ID_file,'Duty_cycle\n');
            temp=sprintf('%.4g\n\n',app.para.Value);
            fprintf(ID_file,temp);
            
            fprintf(ID_file,'Solvent_stadard_deviation_mfile\n');
            temp=sprintf('%s\n\n',app.noise_text.Value);
            fprintf(ID_file,'%s',temp);
            
            fprintf(ID_file,'Fraction_of_reactants\n');
            temp=sprintf('%s\n\n',app.fraction_gro.Value);
            fprintf(ID_file,'%s',temp);
            
            fprintf(ID_file,'Fraction_of_products\n');
            temp=sprintf('%s\n\n',app.fraction_pro.Value);
            fprintf(ID_file,'%s',temp);
            
            fprintf(ID_file,'Excitation_ratio_thermal_changes_only\n');
            temp=sprintf('%f\n\n',app.vibcool.Value);
            fprintf(ID_file,'%s',temp);
            
            fprintf(ID_file,'Result_files_directory\n');
            temp=sprintf('%s\n\n',app.work_dir.Value);
            fprintf(ID_file,'%s',temp);
            
            fprintf(ID_file,'dSdT_switch\n');
            temp=sprintf('%s\n\n',app.dt_switch.Value);
            fprintf(ID_file,'%s',temp);
            
            fprintf(ID_file,'dSdrho_switch\n');
            temp=sprintf('%s\n\n',app.drho_switch.Value);
            fprintf(ID_file,'%s',temp);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            fprintf(ID_file,'sample_detector_distance\n');
            temp=sprintf('%f\n\n',app.sample_distance.Value);
            %temp=sprintf('%s\n\n',app.sample_distance.Value);
            fprintf(ID_file,'%s',temp);
            
            fprintf(ID_file,'cage_switch\n');
            temp=sprintf('%s\n\n',app.cage_knob.Value);
            fprintf(ID_file,'%s',temp);
            
            fprintf(ID_file,'cage_name\n');
            temp=sprintf('%s\n\n',app.cage_name.Value);
            fprintf(ID_file,'%s',temp);
            
            fclose(ID_file);
            
            dirinfo=dir(name_file);
            
            temp=sprintf('Saving path : %s/%s',dirinfo.folder,dirinfo.name);
            msgbox({'Configuration file saved successfully' temp},'Scube')
            
        end
        
        function applyconfig(app)
            app.config.path_gro=[];
            app.config.path_pro=[];
            
            app.config.Solvent=app.Solvent.Value;
            app.config.path_gro=app.path_gro.Value;
            app.config.path_pro=app.path_pro.Value;
            app.config.conc=app.conc.Value;
            app.config.exct=app.exct.Value;
            app.config.wave=app.wave.Value;
            app.config.hour=app.hour.Value;
            app.config.repe=app.pulse_repetition.Value;
            app.config.para=app.para.Value;
            app.config.accul=1;
            app.config.noise=app.noise_text.Value;
            app.config.fraction_gro_char=app.fraction_gro.Value;
            app.config.fraction_pro_char=app.fraction_pro.Value;
            app.config.vibcool=app.vibcool.Value;
        end
        
        function Solvent_switch(app)
            switch app.config.Solvent
                case 'Custom'
                    
                    work_dir_temp=app.work_dir.Value;
                    temp=sprintf('%s/custom_solvent.txt',work_dir_temp);
                    solvent_data=dlmread(temp);
                    temp=sprintf('%s/custom_solvent_hydrodynamics.txt',work_dir_temp);
                    hydrodynamics_data=dlmread(temp);
                    
                    app.config.mw=solvent_data(1);
                    app.config.dens=solvent_data(2);
                    app.config.speed_of_sound=solvent_data(3);
                    app.config.c_v=solvent_data(4);
                    app.config.c_p=solvent_data(5);
                    app.config.chi_t =solvent_data(6);
                    app.config.alpha_p =solvent_data(7);
                    solvent_element_temp=solvent_data(8:end);
                    numoftext=length(solvent_element_temp)/2;
                    app.config.solvent_element=reshape(solvent_element_temp,2,numoftext/2);
                    
                    app.config.q_dt=hydrodynamics_data(:,1);
                    app.config.dt=hydrodynamics_data(:,2);
                    app.config.drho=hydrodynamics_data(:,3);
                    
                    
                case  'Acetonitrile(CH3CN)'
                    
                    %% Get physical parameter of solvent.
                    
                    app.config.mw=41.05e-3; % Kg/mol-1
                    app.config.dens=0.786e3; % Kg/m3 All Mw, dens From Wiki
                    app.config.speed_of_sound=1278.0; % m/s
                    app.config.c_v=63.53; % J/mol/K
                    app.config.c_p=90.00; % J/mol/K
                    app.config.chi_t =11.54e-10; % Pa-1, (N/m^2)-1, iso;thermal compression coefficient
                    app.config.alpha_p =1.3713e-3; % k /K, isobaric thermal expansion coefficient
                    
                    %% Get MD results.
                    load('mecn_data'); % mecn.static(q by 2), mecn.dt(q by 2), mecn.drho (q by 2)
                    
                    %app.config.static=mecn.static(:,2);
                    %app.config.q_static=mecn.static(:,1);
                    
                    app.config.q_dt=mecn.dt(:,1);
                    app.config.dt=mecn.dt(:,2);
                    app.config.drho=mecn.drho(:,2);
                    
                    app.config.solvent_element=[1,3;6,2;7,1];
                    
                    %% Get PAL-XFEL results.
                    %                     load('mecn_palxfel');
                    %
                    %                     app.config.q=mecn_palxfel.q;
                    %                     app.config.mean=mecn_palxfel.mean;
                    %                     app.config.std=mecn_palxfel.std;
                    %                     app.config.photonflux=mecn_palxfel.photon_flux;
                    
                    %disp(app.config) %Debug
                    
                case 'Cyclohexane(C6H12)'
                    
                    %% Get physical parameter of solvent.
                    
                    app.config.mw=0.0842; % Kg/mol-1
                    app.config.dens=779.600; % Kg/m3 All Mw, dens From Wiki
                    app.config.speed_of_sound=1.2785e3; % m/s
                    app.config.c_v=113.60; % J/mol/K
                    app.config.c_p=156.0; % J/mol/K
                    app.config.chi_t =8.2490e-10; % Pa-1, (N/m^2)-1, iso;thermal compression coefficient
                    app.config.alpha_p =0.0012; % k /K, isobaric thermal expansion coefficient
                    
                    %% Get MD results.
                    load('cyclohexane_data'); % mecn.static(q by 2), mecn.dt(q by 2), mecn.drho (q by 2)
                    
                    %app.config.static=cc.static(:,2);
                    %app.config.q_static=cc.static(:,1);
                    
                    app.config.q_dt=cc.dt(:,1);
                    app.config.dt=cc.dt(:,2);
                    app.config.drho=cc.drho(:,2);
                    
                    app.config.solvent_element=[1,12;6,6];
                    
                    %% Get PAL-XFEL results.
                    %load('cc_palxfel_scaling');
                    %                     load('cyclohexane_esrf_1702_exptime1p5s_scaling');
                    %
                    %                     %app.config.q=cc_palxfel.q;
                    %                     %app.config.mean=cc_palxfel.mean;
                    %                     %app.config.std=cc_palxfel.std;
                    %                     %app.config.photonflux=cc_palxfel.photon_flux;
                    %
                    %                     app.config.q=cyclohexane_esrf_1702_exptime1p5s.q;
                    %                     app.config.mean=cyclohexane_esrf_1702_exptime1p5s.mean;
                    %                     app.config.std=cyclohexane_esrf_1702_exptime1p5s.std;
                    %                     app.config.photonflux=cyclohexane_esrf_1702_exptime1p5s.photon_flux;
                    
                case 'Water(H2O)'
                    
                    %% Get physical parameter of solvent.
                    
                    app.config.mw=18.01528e-3; % Kg/mol-1
                    app.config.dens=997.0474; % Kg/m3 All Mw, dens From Wiki
                    app.config.speed_of_sound=1481; % m/s
                    app.config.c_v=74.53;%113.60; % J/mol/K
                    app.config.c_p=75.327;%156.0; % J/mol/K
                    app.config.chi_t =4.6e-10; % Pa-1, (N/m^2)-1, isothermal compression coefficient
                    app.config.alpha_p =0.207e-3; % k /K, isobaric thermal expansion coefficient
                    
                    %% Get MD results.
                    load('h2o_data'); % mecn.static(q by 2), mecn.dt(q by 2), mecn.drho (q by 2)
                    
                    %app.config.static=h2o.static(:,2);
                    %app.config.q_static=h2o.static(:,1);
                    
                    app.config.q_dt=h2o.dt(:,1);
                    app.config.dt=h2o.dt(:,2);
                    app.config.drho=h2o.drho(:,2);
                    
                    app.config.solvent_element=[1,2;8,1];
                    
                    %                     %% Get PAL-XFEL results.
                    %                     load('h2o_palxfel_scaling');
                    %
                    %                     app.config.q=h2o_palxfel.q;
                    %                     app.config.mean=h2o_palxfel.mean;
                    %                     app.config.std=h2o_palxfel.std;
                    %                     app.config.photonflux=h2o_palxfel.photon_flux;
                    
                case 'Methanol(CH3OH)'
                    
                    app.config.mw=0.0842; % Kg/mol-1
                    app.config.dens=791.0; % Kg/m3 All Mw, dens From Wiki
                    app.config.speed_of_sound=1143.0; % m/s
                    app.config.c_v=69.50; % J/mol/K
                    app.config.c_p=81.10; % J/mol/K
                    app.config.chi_t =1.214e-9; % Pa-1, (N/m^2)-1, iso;thermal compression coefficient
                    app.config.alpha_p =1.49e-3; % k /K, isobaric thermal expansion coefficient
                    
                    %% Get MD results.
                    load('meoh_data'); % mecn.static(q by 2), mecn.dt(q by 2), mecn.drho (q by 2)
                    
                    %                     app.config.static=meoh.static(:,2);
                    %                     app.config.q_static=meoh.static(:,1);
                    
                    app.config.q_dt=meoh.dt(:,1);
                    app.config.dt=meoh.dt(:,2);
                    app.config.drho=meoh.drho(:,2);
                    
                    app.config.solvent_element=[1,4;6,1;8,1];
                    
                    %                     %% Get PAL-XFEL results.
                    %                     load('meoh_palxfel_scaling');
                    %
                    %                     app.config.q=meoh_palxfel.q;
                    %                     app.config.mean=meoh_palxfel.mean;
                    %                     app.config.std=meoh_palxfel.std;
                    %                     app.config.photonflux=meoh_palxfel.photon_flux;
                    %
                    
                otherwise
                    disp('Error!!!!!')
                    disp('The solvent is a non-listed solvent')
                    disp('list: Acetonitrile, Cyclohexane, Methanol, Water')
            end
        end
        
        
        function read_coord(app)
            
            app.config.numgro=length(app.path_gro.Value);
            app.config.numpro=length(app.path_pro.Value);
            
            app.config.coord_gro=cell(app.config.numgro);
            app.config.coord_pro=cell(app.config.numpro);
            
            for ii=1:app.config.numgro
                try
                    app.config.coord_gro{ii}=importdata(app.config.path_gro{ii});
                catch
                    error('Coordination files error.');
                end
                app.config.coord_gro{ii}.num=sym2num(app,app.config.coord_gro{ii}.textdata);
            end
            
            for ii=1:app.config.numpro
                try
                    app.config.coord_pro{ii}=importdata(app.config.path_pro{ii});
                catch
                    error('Coordination files error.');
                end
                app.config.coord_pro{ii}.num=sym2num(app,app.config.coord_pro{ii}.textdata);
            end
            
            %%Debug
            %disp(app.config);
            %disp(app.config.coord_gro);
            %disp(app.config.coord_pro);
        end
        
        function Results=debye_cal(app,coord,q)
            numofq=length(q);
            [numofatom,~]=size(coord.data);
            x=coord.data(:,1);
            y=coord.data(:,2);
            z=coord.data(:,3);
            atom=coord.num(:);
            
            Termfirst=zeros(numofq,1);
            for i=1:numofatom
                Termfirst=Termfirst+app.cal.form_factor_table(:,atom(i)).^2;
            end
            
            Termsecond=zeros(numofq,1);
            for i=1:numofatom-1
                for j=i+1:numofatom
                    r=sqrt((x(i)-x(j))^2+(y(i)-y(j))^2+(z(i)-z(j))^2);
                    for n=1:numofq
                        Termsecond(n)=Termsecond(n)+app.cal.form_factor_table(n,atom(i))*app.cal.form_factor_table(n,atom(j))*sin(q(n)*r)/(q(n)*r);
                    end
                end
            end
            
            Results=Termfirst+2*Termsecond;


        end
        
        function simulation(app)
            app.config.photonflux_ref=app.config.pulse_repetition_ref*app.config.exposure_time_ref*app.config.photons_per_pulse_ref;
            app.config.photonflux=app.pulse_repetition.Value*app.exposure_time.Value*app.photons_per_pulse.Value;
            app.config.photonflux_scaling=app.config.photonflux/app.config.photonflux_ref; % Flux scaling factor
            
            app.config.numofimage=round(app.config.hour*3600*app.config.para/2/app.exposure_time.Value); % total image number
            
            numofq=length(app.config.q);
            
            app.config.stdscaling=sqrt(app.config.photonflux_scaling);
            
            %disp(app.config.stdscaling)
            %             app.config.stdscaling_gro=sqrt(app.config.photonflux_scaling*abs(app.config.mean+app.config.debye_gro)./abs(app.config.mean));
            %             app.config.stdscaling_gro=app.config.photonflux_scaling;
            %             app.config.stdscaling_pro=sqrt(app.config.photonflux_scaling*abs(app.config.mean+app.config.exct*app.config.debye_pro+(1-app.config.exct)*app.config.debye_gro)./abs(app.config.mean));
            %             app.config.stdscaling_pro=app.config.photonflux_scaling;
            
            %%% Get mean
            %             I_std_species_total=zeros(numofq,app.config.numofimage);% Temp remove
            
            I_std_species1=zeros(numofq,1);
            I_std_species2=zeros(numofq,1);
            for ii=1:app.config.numofimage
                if ii==1
                    fprintf('Current simulation image : %d/%d (%.0f%%)\n',ii,app.config.numofimage,100*ii/app.config.numofimage);
                elseif ii==app.config.numofimage
                    fprintf('Current simulation image : %d/%d (%.0f%%)\n',ii,app.config.numofimage,100*ii/app.config.numofimage);
                elseif 0==rem(ii,1000)
                    fprintf('Current simulation image : %d/%d (%.0f%%)\n',ii,app.config.numofimage,100*ii/app.config.numofimage);
                else
                end
                random_noise1 = randn(numofq,1);
                random_noise2 = randn(numofq,1);
                sum_temp1=app.config.std.*random_noise1./app.config.stdscaling;
                sum_temp2=app.config.std.*random_noise2./app.config.stdscaling;
                I_std_species1=I_std_species1+sum_temp1;
                I_std_species2=I_std_species2+sum_temp2;
                %                 I_std_species_total(:,ii)=sum_temp1-sum_temp2; % Temp remove
            end
            %             app.results.std=std(I_std_species_total,0,2)*app.config.scaling_factor; %% Temp_remove plz
            app.results.err=(I_std_species2-I_std_species1)/(app.config.numofimage)*app.config.scaling_factor;
        end
        
        function merge_data(app)
            
            %             q=app.config.q;
            solute=app.results.solute;
            dsdt=app.results.dsdt;
            dsdrho=app.results.dsdrho;
            err=app.results.err;
            cage=app.results.cage;
            
            app.results.sum=solute+dsdt+dsdrho+err+cage;
            app.results.sumwoerr=app.results.sum-app.results.err;
            
        end
        
        function writeoutput(app,time,path)
            writeconfig(app,time,path)
            
            file_name=sprintf('%s/Scube_results_%s.txt',path,time);
            file_id=fopen(file_name,'w');
            fprintf(file_id,'#q\t#%s\t#Simulation_without_noise\t#Solute\t#Solvent_heating_temperatue\t#Solvent_heating_density\t#Cage','Simulation_with_noise');
            for ii=1:length(app.config.q)
                fprintf(file_id,'\n %-12.6g \t %-12.6g \t %-12.6g \t%-12.6g \t%-12.6g \t%-12.6g \t%-12.6g',app.config.q(ii),app.results.sum(ii),app.results.sumwoerr(ii),app.results.solute(ii),app.results.dsdt(ii),app.results.dsdrho(ii),app.results.cage(ii));
            end
            fclose(file_id);
            
            file_name=sprintf('%s/Scube_results_qSq_%s.txt',path,time);
            file_id=fopen(file_name,'w');
            fprintf(file_id,'#q\t#%s\t#Simulation_without_noise\t#Solute\t#Solvent_heating_temperatue\t#Solvent_heating_density\t#Cage','Simulation_with_noise');
            for ii=1:length(app.config.q)
                fprintf(file_id,'\n %-12.6g \t %-12.6g \t %-12.6g \t%-12.6g \t%-12.6g \t%-12.6g \t%-12.6g',app.config.q(ii),app.config.q(ii).*app.results.sum(ii),app.config.q(ii).*app.results.sumwoerr(ii),app.config.q(ii).*app.results.solute(ii),app.config.q(ii).*app.results.dsdt(ii),app.config.q(ii).*app.results.dsdrho(ii),app.config.q(ii).*app.results.cage(ii));
            end
            fclose(file_id);
        end
        
        function drawdata(app)
            figure
            set(gcf,'numbertitle','off','name', 'S-cube results');
            plot(app.config.q,[app.config.q.*app.results.sum,app.config.q.*app.results.sumwoerr,app.config.q.*app.results.solute,app.config.q.*app.results.dsdt,app.config.q.*app.results.dsdrho,app.config.q.*app.results.cage]);
            title('Simulation results');
            xlabel('q');
            ylabel('qS(q)');
            legend(["Simulation(with noise)","Simulation(w/o noise)","Solute","Solvent heating(temperaute)","Solvent heating(density)","Cage"]);
        end
        
        
        function form_factor_table_make(app)
            numofq=length(app.config.q);
            numofatom=104;
            
            form_factor_table=zeros(numofq,numofatom);
            for ii=1:numofq % ii : temp_q
                for jj=1:numofatom % jj : temp_atomic_number
                    form_factor_table(ii,jj)=form_factor(app,jj,app.config.q(ii));
                end
            end
            app.cal.form_factor_table=form_factor_table;
        end
        
        
        function read_fraction(app)
            %% Products
            command_fraction_read=sprintf('app.config.fraction_gro=[%s];',app.config.fraction_gro_char);
            eval(command_fraction_read);
            %% Reactants
            command_fraction_read=sprintf('app.config.fraction_pro=[%s];',app.config.fraction_pro_char);
            eval(command_fraction_read);
            %% Debug
            %             disp(app.config.fraction_gro)
            %             disp(app.config.fraction_pro)
        end
        
        
        function gr = gr_convert(~,r,VDR,R)
            numofr=length(r);
            
            bool_0=r<=VDR;
            num_0=sum(bool_0);
            
            
            bool_1=r>=R+VDR;
            bool_cal=VDR<r&r<R+VDR;
            num_cal=sum(bool_cal);
            dy=1/(num_cal+1);
            
            gr=zeros(1,numofr);
            for ii=1:num_cal
                gr(num_0+ii)=ii*dy;
            end
            gr=gr+bool_1.*ones(1,numofr);
        end
        
        function num=sym2num_pair(app,text)
            posi=strfind(text,'_');
            if 1==isempty(posi)
                textdata=text;
            else
                textdata=text(1:posi-1);
            end
            for i=1:104
                if strcmp(textdata,app.constant.elements{i})==1
                    num=i;
                else
                end
            end
        end
        
        function [pair,Z,N,PDF_cutoff]=pair_analyzer_v2(app,pair_cell,atom)
            numofpair=length(pair_cell);
            pair=cell(2,numofpair);
            Z=nan(2,numofpair);
            N=nan(2,numofpair);
            PDF_cutoff=nan(1,numofpair);
            for ii=1:numofpair
                posi1=strfind(pair_cell{1,ii},'-');
                pair{1,ii}=pair_cell{1,ii}(1:posi1-1);
                pair{2,ii}=pair_cell{1,ii}(posi1+1:end);
                for jj=1:2
                    Z(jj,ii)=sym2num_pair(app,pair{jj,ii});
                    N(jj,ii)=atom_count(app,pair{jj,ii},atom);
                end
                PDF_cutoff(ii)=app.constant.vanderwaals(Z(1,ii))+app.constant.vanderwaals(Z(2,ii));
            end
        end
    end
    
    methods (Access = public)
        
        function basic_declare(app)
            
            load('constant')
            
            app.constant=constant; %#ok<ADPROP> 
            
            app.config.cvcp=app.config.c_v/app.config.c_p;
            app.config.sigma=(app.config.c_v-app.config.c_p)*app.config.chi_t/app.config.alpha_p;
            app.config.R=app.config.dens/app.config.mw/app.config.conc;
            app.config.conc_solv=app.config.dens/app.config.mw;
            
            
        end
        
        function num=sym2num(app,textdata)
            numofatom=length(textdata);
            num=nan(numofatom,1);
            for atom=1:numofatom % change atom to element number
                for i=1:104
                    if strcmp(textdata{atom},app.constant.elements{i})==1
                        num(atom)=i;
                    else
                    end
                end
            end
        end
        
        function debye_solver(app)
            form_factor_table_make(app);
            sum_temp=zeros(length(app.config.q),1);
            for ii=1:app.config.numgro
                if ii==1
                    fprintf('Start debye calculation of %d''st ground state coordination\n',ii);
                elseif ii==2
                    fprintf('Start debye calculation of %d''nd ground state coordination\n',ii);
                elseif ii==3
                    fprintf('Start debye calculation of %d''rd ground state coordination\n',ii);
                else
                    fprintf('Start debye calculation of %d''th ground state coordination\n',ii);
                end
                %                 tic
                app.config.coord_gro{ii}.debye=debye_cal(app,app.config.coord_gro{ii},app.config.q)*app.config.fraction_gro(ii);
                sum_temp=sum_temp+app.config.coord_gro{ii}.debye;
                %                 toc
            end
            app.config.debye_gro=sum_temp*app.config.exct/app.config.R;
            sum_temp=zeros(length(app.config.q),1);
            for ii=1:app.config.numpro
                if ii==1
                    fprintf('Start debye calculation of %d''st intermediate state/product coordination\n',ii);
                elseif ii==2
                    fprintf('Start debye calculation of %d''nd intermediate state/product coordination\n',ii);
                elseif ii==3
                    fprintf('Start debye calculation of %d''rd intermediate state/product coordination\n',ii);
                else
                    fprintf('Start debye calculation of %d''th intermediate state/product coordination\n',ii);
                end
                %                 tic
                app.config.coord_pro{ii}.debye=debye_cal(app,app.config.coord_pro{ii},app.config.q)*app.config.fraction_pro(ii);
                sum_temp=sum_temp+app.config.coord_pro{ii}.debye;
                %                 toc
            end
            app.config.debye_pro=sum_temp*app.config.exct/app.config.R;
            
            app.config.solute=app.config.debye_pro-app.config.debye_gro;
            app.results.solute=app.config.solute;
            
            %             numofstate=2;
            %             temp=app.config.coord2state(app.config.coord);
            %             for ii=1:numofstate
            %                 app.config.state{ii}.debye=temp(:,ii)/app.config.R;
            %             end
            
            
            %%Debug
            %figure
            %plot(app.config.q,app.config.solute);
            
        end
        
        function form=form_factor(app,atomic_number,q)
            s=q/4/pi;
            %% Simple calculate for An,Bn,Cn term (D. Waasmaier and A. Kirfel)
            temp=0;
            for k=1:5
                temp=temp+app.constant.Xfactor(atomic_number,k)*exp(-app.constant.Xfactor(atomic_number,k+6)*s*s);
            end
            form=temp+app.constant.Xfactor(atomic_number,6);
        end
        
        function solvent_heating(app)
            dtdrho(app)
            app.config.dsdt_interp=interp1(app.config.q_dt,app.config.dt,app.config.q,'spline');
            app.config.dsdrho_interp=interp1(app.config.q_dt,app.config.drho,app.config.q,'spline');
            
            %             app.config.dsdt_final=app.config.dT_max*app.config.dsdt_interp;
            %             app.config.dsdrho_final=app.config.drho_max*app.config.dsdrho_interp;
            %
            app.results.dsdt=app.config.dT_max*app.config.dsdt_interp;
            app.results.dsdrho=app.config.drho_max*app.config.dsdrho_interp;
            
            
            %             figure
            %             plot(app.config.q,[app.config.dsdt_final,app.config.dsdrho_final]);
        end
        
        function dtdrho(app)
            %% Q(t)
            Qt=app.constant.Na/app.config.R*app.constant.ha*app.constant.speed_l/(app.config.wave*1e-9)*(app.config.exct+app.config.vibcool);
            %             Qt=app.constant.Na/app.config.R*app.constant.ha*app.constant.speed_l/(app.config.wave*1e-9);
            %% Delta T(t) Max
            if strcmp(app.dt_switch.Value,'On')
                app.config.dT_max = (Qt/app.config.c_v);
            elseif strcmp(app.dt_switch.Value,'Off')
                app.config.dT_max=0;
            else
                disp('Error!! Wrong dt_switch name')
            end
            dT_temp=(Qt/app.config.c_p);
            %% Delta rho(t) Max
            if strcmp(app.drho_switch.Value,'On')
                app.config.drho_max = app.config.dens*(-app.config.alpha_p*dT_temp);
            elseif strcmp(app.drho_switch.Value,'Off')
                app.config.drho_max=0;
            else
                disp('Error!! Wrong drho_switch name')
            end
        end
        
        %         function axout = molecule3D(varargin)
        %             RC = 0.2; % radius of bonds
        %             DBOND = 1.6; % maximal distance of atoms forming bond
        %
        %             % resolution of spheres and cylinders
        %             NS = 50; % spheres, more looks smoother
        %             NB = 50; % more looks smoother
        %
        %             % check input arguments
        %             narginchk(3,inf);
        %
        %             if numel(varargin{2}) == 1 && ishghandle(varargin{2}(1)) && ...
        %                     isequal(lower(char(get(varargin{2}(1),'type'))),'axes')
        %                 ax = varargin{2};
        %                 offs = 1;
        %             else
        %                 ax = [];
        %                 offs = 0;
        %             end
        %
        %             xyz = varargin{2+offs};
        %             Na = size(xyz,1); % number of atoms in molecule
        %
        %             lab = varargin{3+offs};
        %             if ~iscellstr(lab)
        %                 error('Input LAB has to be a cell string')
        %             end
        %             lab = lab(:); % convert to column vector
        %
        %             if nargin - offs < 4
        %                 style = 'ballstick'; % set default style to rad/stick
        %             else
        %                 style = varargin{3+offs};
        %             end
        %
        %             if ~any(strcmp(style,{'ballstick','licorice','large','superlarge'}))
        %                 warning('Style "%s" not found.',style)
        %                 style = 'ballstick'; % fallback
        %             end
        %
        %             if size(xyz,2) ~= 3 || Na < 1
        %                 error('First argument should be N times 3 matrix.')
        %             end
        %
        %             if numel(lab) ~= Na
        %                 error('Number of labels does not match number of columns.')
        %             end
        %
        %             % prepare axis
        %             ax = newplot(ax);
        %             axes(ax);
        %             set(ax,'Visible','off','DataAspectRatio',[1 1 1]) % fix aspect ratio
        %
        %             light('Position',[1 1 2]); % add some light
        %
        %             % all combinations of atom pairs
        %             pairs = combnk(1:Na,2);
        %             % all interatomic distances
        %             ds = sqrt(sum((xyz(pairs(:,1),:) - xyz(pairs(:,2),:)).^2,2));
        %
        %             % find bonds based on distances of atoms
        %             ks = find(ds < DBOND & ~(strcmp(lab(pairs(:,1)),'H') & ...
        %                 strcmp(lab(pairs(:,2)),'H')))';
        %
        %             % draw sphere with adapted radius for each element / line in xyz
        %             for k = 1:size(xyz,1)
        %                 thiscol = col(app,lab{k});
        %
        %                 switch style
        %                     case 'ballstick'
        %                         thisr = rad(app,lab{k});
        %                     case 'licorice'
        %                         thisr = RC;
        %                     case 'large'
        %                         thisr = 2*rad(app,lab{k});
        %                     case 'superlarge'
        %                         thisr = 3*rad(app,lab{k});
        %                 end
        %
        %                 % basic sphere
        %                 [sx,sy,sz] = sphere(NS);
        %
        %                 % draw sphere
        %                 surface('XData',xyz(k,1) + thisr*sx,'YData',xyz(k,2) + thisr*sy, ...
        %                     'ZData',xyz(k,3) + thisr*sz,'FaceColor',thiscol, ...
        %                     'EdgeColor','none','FaceLighting','gouraud')
        %             end
        %
        %             % draw cylinders for each bond
        %             for k = ks % draw sticks for all bounds
        %                 r1 = xyz(pairs(k,1),:); % coordinates atom 1
        %                 r2 = xyz(pairs(k,2),:); % coordinates atom 2
        %
        %                 % bond angles in spherical coordinates
        %                 v = (r2-r1)/norm(r2-r1);
        %                 phi = atan2d(v(2),v(1));
        %                 theta = -asind(v(3));
        %
        %                 % bond distance minus sphere radii
        %                 bd = ds(k) - rad(app,lab{pairs(k,1)}) - rad(app,lab{pairs(k,2)});
        %                 cyl2 = rad(app,lab{pairs(k,1)}) + bd/2; % length half bond cylinder
        %                 cyl1 = ds(k); % length full bond cylinder
        %
        %                 % prototype cylinders for bond
        %                 [z,y,x] = cylinder(RC,NB); % full bond cylinder
        %                 x(2,:) = x(2,:) * cyl1; % adjust length
        %                 [z2,y2,x2] = cylinder(RC*1.01,NB); % half bond cylinder, thicker
        %                 x2(2,:) = x2(2,:) * cyl2; % adjust length
        %
        %                 % rotate cylinders to match bond vector v
        %                 for kk = 1:numel(x)
        %                     vr = [x(kk); y(kk); z(kk);];
        %                     vr = rotz_jkim(phi)*roty_jkim(theta)*vr;
        %                     x(kk) = vr(1);
        %                     y(kk) = vr(2);
        %                     z(kk) = vr(3);
        %
        %                     vr = [x2(kk); y2(kk); z2(kk);];
        %                     vr = rotz_jkim(phi)*roty_jkim(theta)*vr;
        %                     x2(kk) = vr(1);
        %                     y2(kk) = vr(2);
        %                     z2(kk) = vr(3);
        %                 end
        %
        %                 % get colors of both atoms
        %                 thiscol1 = col(app,lab{pairs(k,2)});
        %                 thiscol2 = col(app,lab{pairs(k,1)});
        %
        %                 % full bond color 1
        %                 surface('XData',r1(1) + x,'YData',r1(2) + y,...
        %                     'ZData',r1(3) + z,'FaceColor',thiscol1,...
        %                     'EdgeColor','none','FaceLighting','gouraud')
        %
        %                 % half bond color 2
        %                 surface('XData',r1(1) + x2,'YData',r1(2) + y2,...
        %                     'ZData',r1(3) + z2,'FaceColor',thiscol2,...
        %                     'EdgeColor','none','FaceLighting','gouraud')
        %             end
        %
        %             if nargout > 0
        %                 axout = ax;
        %             end
        %
        %         end
        %
        %         function c = col(app,s)
        %             switch s
        %                 case  'H', c = [1 1 1];
        %                 case  'C', c = [0.2 0.2 0.2];
        %                 case  'O', c = [1.0 0.1 0.1];
        %                 case  'N', c = [0.5 0.5 0.6];
        %                 case  'I', c = [0.4 0.1 0.7];
        %                 case  {'F','Cl'}, c = [0.2 0.9 0.2];
        %                 case  'Br', c = [0.6 0.1 0.1];
        %                 case  {'He','Ne','Ar','Kr','Xe'}, c = [0.2 1.0 1.0];
        %                 case  'P', c = [1.0 0.6 0.2];
        %                 case  'S', c = [0.9 0.9 0.2];
        %                 case  'B', c = [1.0 0.7 0.5];
        %                 case  {'Li','Na','K','Rb','Cs','Fr'}, c = [0.5 0.1 1.0];
        %                 case  {'Be','Mg','Ca','Sr','Ba','Ra'}, c = [0.1 0.5 0.1];
        %                 case  'Ti', c = [0.6 0.6 0.6];
        %                 case  'Fe', c = [0.9 0.5 0.1];
        %                 otherwise, c = [0.9 0.5 1.0];
        %             end
        %         end
        %
        %         function r=rad(app,s)
        %             switch s
        %                 case  'H', r = 0.4;
        %                 case  'C', r = 0.5;
        %                 case  'O', r = 0.5;
        %                 case  'N', r = 0.5;
        %                 case  'I', r = 0.6;
        %                 otherwise, r = 0.5;
        %             end
        %         end
        
        
        function get_exp_solvent_data(app)
            try
                load(app.noise_text.Value);
            catch
                error('Unknown error found in solvent standard deviation files. Check the files.');
            end
%             app.config.q=q_exp;
%             app.config.mean=mean_exp;
%             app.config.std=std_exp;
%             app.config.photonflux=1;
%             app.config.scaling_factor=Scaling_factor;
            
            app.config.q=q_exp;
            app.config.std=std_exp;
            app.config.scaling_factor_ref=scaling_factor;
            app.config.pulse_repetition_ref=pulse_repetition; %#ok<ADPROP> 
            app.config.exposure_time_ref=exposure_time; %#ok<ADPROP> 
            app.config.photons_per_pulse_ref=photons_per_pulse; %#ok<ADPROP> 
            %app.config.scaling_factor_ref=scaling_factor;
            app.config.lambda_ref=lambda;
            app.config.distance_ref=distance;
            
            app.config.distance=app.config.distance_ref;
            app.config.scaling_factor=app.config.scaling_factor_ref;
            app.config.lambda=app.config.lambda_ref;

            
        end
        
        
        function Cage_cal(app)
            %% Add path
            switch app.cage_knob.Value
                case 'None'
                    app.config.cage=zeros(length(app.config.q),1);
                    app.results.cage=app.config.cage;
                case 'MD simulations'
                    cage_data=dlmread(app.cage_name.Value);
                    q_MD=cage_data(:,1);
                    sq_MD=cage_data(:,2);
                    
                    cage_temp=interp1(q_MD,sq_MD,app.config.q,'spline');
                    
                    app.config.cage=cage_temp*app.config.exct/app.config.R;
                    app.results.cage=app.config.cage;
                    
                case 'Hard-spheres'
%                     path_current=app.work_dir.Value ;
%                     temp=sprintf('%s/codes',path_current);
%                     addpath(temp)
                    numofsolv=1024;
                    q=app.config.q;
                    numofq=length(q);
                    mw=app.config.mw * 1e3; % g/mol
                    na=app.constant.Na;
                    dens=app.config.dens; % Kg/m3
                    V=numofsolv*mw/na/(dens*1000)*10^30;
                    
                    cage_elements_gro=zeros(numofq,app.config.numgro);
                    cage_elements_pro=zeros(numofq,app.config.numpro);
                    
                    for ii=1:app.config.numgro+app.config.numpro
                        % Geo specific
                        clearvars Geo        
                        if ii < app.config.numgro+1
                            Geo=app.config.coord_gro{ii};
                        else
                            Geo=app.config.coord_pro{ii-app.config.numgro};
                        end
                        
                        
                        Geo.num=sym2num_old(app,Geo);
                        Geo.R=geo2R(app,Geo.data);
                        Geo.VDR=geo2VDR(app,Geo.num);
                        
                        % Make element
                        %num_atom_type_solv=size(app.config.solvent_element,1);
                        atom_type_solv=app.config.solvent_element(:,1);
                        num_atom_type_solv=length(atom_type_solv);
                        atom_type_solu=unique(Geo.num);
                        num_atom_type_solu=length(atom_type_solu);
                        atom=cell(num_atom_type_solv,2);
                        solvent=cell(1,num_atom_type_solv);
                        for index_atom_type=1:num_atom_type_solv
                            solvent(index_atom_type)=app.constant.elements(atom_type_solv(index_atom_type));
                        end
                        for index_atom=1:num_atom_type_solv
                            atom(index_atom,:)={app.constant.elements{app.config.solvent_element(index_atom,1)},numofsolv*app.config.solvent_element(index_atom,2)};
                        end
                        for index_atom=num_atom_type_solv+1:num_atom_type_solv+num_atom_type_solu
                            temp_element=sprintf('%s_1',app.constant.elements{atom_type_solu(index_atom-num_atom_type_solv)});
                            atom(index_atom,:)={temp_element,sum(Geo.num==atom_type_solu(index_atom-num_atom_type_solv))};
                        end
                        
                        
                        % Pair Cell Maker
                        numoftype=length(atom);
                        numofpair=sum([1:1:numoftype]); %#ok<NBRAK> 
                        clear pair_cell
                        pair_cell=cell(1,numofpair);
                        count=0;
                        for type_index=1:numoftype
                            for type_index_2=type_index:numoftype
                                count=count+1;
                                temp=sprintf('%s-%s',atom{type_index,1},atom{type_index_2,1});
                                pair_cell{count}=temp;
                            end
                        end


                        % Pair Maker
                        [pair,Z,N,PDF_cutoff]=pair_analyzer_v2(app,pair_cell,atom);
                        r=[0.01:0.01:20]; %#ok<NBRAK> 
                        numofr=length(r);
                        gr=zeros(numofr,numofpair);
                        for pair_counter=1:numofpair
                            gr(:,pair_counter)=gr_convert(app,r,PDF_cutoff(pair_counter),Geo.R);
                        end
                        dr=diff(r);
                        %disp(gr)
                        
                        % Formfactor
                        f=nan(2,numofpair,numofq);
                        for counter_pair=1:numofpair
                            for counter=1:2
                                for counter_q=1:numofq
                                    f(counter,counter_pair,counter_q)=form(app,Z(counter,counter_pair),q(counter_q));
                                end
                            end
                        end
                        signal=zeros(numofq,numofpair);
                        for iii=1:numofq
                            for jj=1:numofpair
                                fact=2;
                                if 1==strcmp(pair{1,jj},pair{2,jj})
                                    signal(iii,jj)=signal(iii,jj)+f(1,jj,iii)^2*N(1,jj);
                                    fact=1;
                                else
                                end
                                if gr(end,jj)>0.25
                                    substration=1;
                                else
                                    substration=0;
                                end
                                for kk=1:numofr-1
                                    signal(iii,jj)=signal(iii,jj)+N(1,jj)*N(2,jj)/V*f(1,jj,iii)*f(2,jj,iii)*4*pi*r(kk)*sin(q(iii)*r(kk))/q(iii)*(gr(kk,jj)-substration)*fact*dr(kk);
                                end
                            end
                        end
                        %cage_results=[q,sum(signal,2),signal];
                        
                        %% Solvent checker
                        count=0;
                        count2=0;
                        count_array=[];
                        for counter_pair=1:numofpair
                            checker=0;
                            count=count+1;
                            for jj=1:2
                                for kk=1:length(solvent)
                                    if 1==strcmp(pair{jj,counter_pair},solvent{kk})
                                        checker=checker+1;
                                    end
                                end
                            end
                            if checker==1
                                count2=count2+1;
                                count_array(count2)=count; %#ok<AGROW> 
                            end
                        end
                        clear pair_cell_cage
                        pair_cell_cage=cell(1,length(count_array));
                        signal_cage=nan(numofq,length(count_array));
                        for pair_counter=1:length(count_array)
                            pair_cell_cage{pair_counter}=pair_cell{count_array(pair_counter)};
                            signal_cage(:,pair_counter)=signal(:,count_array(pair_counter));
                        end
                        cage_results_temp=sum(signal_cage,2);
                        if ii < app.config.numgro+1
                            cage_elements_gro(:,ii)=cage_results_temp*app.config.fraction_gro(ii);
                        else
                            cage_elements_pro(:,ii-app.config.numgro)=cage_results_temp*app.config.fraction_pro(ii-app.config.numgro);
                        end
                    end
                    app.config.cage_gro=sum(cage_elements_gro,2);
                    app.config.cage_pro=sum(cage_elements_pro,2);
                    app.config.cage=(app.config.cage_pro-app.config.cage_gro)*app.config.exct/app.config.R;
                    app.results.cage=app.config.cage;
            end
        end
        
        function R = geo2R(~,coord)
            COM=zeros(1,3);
            [numofatom,~]=size(coord);
            for ii=1:3
                COM(ii)=sum(coord(:,ii)/numofatom);
            end
            
            dis_COM=(coord-COM).^2;
            
            test2=zeros(1,numofatom);
            for ii=1:numofatom
                test2(ii)=sum(dis_COM(ii,:));
            end
            
            R=sqrt(sum(test2)/numofatom);
        end
        
        function VDradius=geo2VDR(app,atom_numbers)
            VDradius=zeros(1,length(atom_numbers));
            for ii=1:length(atom_numbers)
                VDradius(ii)=app.constant.vanderwaals(atom_numbers(ii));
            end
        end
        
        function number=atom_count(~,text,atom)
            [numofatom,~]=size(atom);
            for ii=1:numofatom
                if 1==strcmp(atom{ii,1},text)
                    number=atom{ii,2};
                else
                end
            end
        end
        
        function results=form(app,atomic_number,q)
            s=q/(4*pi);
            %% Simple calculate for An,Bn,Cn term (D. Waasmaier and A. Kirfel)
            temp=0;
            for k=1:5
                temp=temp+app.constant.Xfactor(atomic_number,k)*exp(-app.constant.Xfactor(atomic_number,k+6)*s*s);
            end
            results=temp+app.constant.Xfactor(atomic_number,6);
        end
        
        function maximum_q_coorection(app)
            
%             app.config.distance_target=app.sample_distance.Value;
%             distance_ratio=app.config.distance_target/app.config.distance;
%             
%             q_max_ref=app.config.q(end);
%             
%             theta_max=asin(app.config.lambda*q_max_ref/4/pi)*180/pi;
%             theta_coorection=atan(tan(2*theta_max/180*pi)/distance_ratio)/2*180/pi;
%             
%             q_max_coorection=sin(theta_coorection/180*pi)*4*pi/app.config.lambda;
%             
%             q_index= app.config.q<=q_max_coorection;
%             
%             app.config.q=app.config.q(q_index);
%             app.config.std=app.config.std(q_index);
            app.config.distance_target=app.sample_distance.Value;
            distance_ratio=app.config.distance_target/app.config.distance;
            
            q_max_ref=app.config.q(end);
            
            theta_max=asin(app.config.lambda*q_max_ref/4/pi)*180/pi;
            
            if theta_max > 45
                error('Mismatch between sacttering vector(q) and X-ray wavelength. Check the q and lambda.')
            end
            
            theta_coorection=atan(tan(2*theta_max/180*pi)/distance_ratio)/2*180/pi;
            
            q_max_coorection=sin(theta_coorection/180*pi)*4*pi/app.config.lambda;
            
            q_index= app.config.q<=q_max_coorection;
            
            app.config.q=app.config.q(q_index);
            app.config.std=app.config.std(q_index);
        end
        
        function num = sym2num_old(app,coord)
            [numofatom,~]=size(coord.data);
            num=nan(numofatom,1);
            for atom=1:numofatom % change atom to element number
                for i=1:104
                    if strcmp(coord.textdata{atom},app.constant.elements{i})==1
                        num(atom)=i;
                    else
                    end
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %files = matlab.apputil.getInstalledAppInfo;
            
            %             for ii=1:length(files)
            %                 if strcmp(files(ii).name,'Scube')
            %                     app_path=sprintf('%s/code',lower(files(ii).location));
            %                     cd(app_path)
            %                 end
            %             end
            
%             set(app.work_dir,'Value',pwd);
%             
%             temp=sprintf('%s/Scube_config*.dat',app.work_dir.Value);
%             
%             list=dir(temp);
%             if ~isempty(list)
%                 
%                 temp=cell(length(list),1);
%                 for ii=1:length(list)
%                     temp{ii}=list(ii).name;
%                 end
%                 sorted=sort(temp);
%                 
%                 for ii=1:length(list)
%                     if strcmp(temp{ii},sorted{end})
%                         index=ii;
%                     end
%                 end
%                 name=list(index).name;
%                 path=list(index).folder;
%                 readconfig(app,path,name,1);
%                 path_name=sprintf('%s/%s',path,name);
%                 set(app.Conf_files_name,'Value',path_name);
%             end

       fprintf('---------------------------------------------------------\n');
       fprintf('                        S-cube                           \n');
       fprintf('    Time-resolved X-ray solution scattering simulator    \n');
       fprintf('---------------------------------------------------------\n');
            
            
        end

        % Callback function
        function Slider_concValueChanged(app, event)
            value = app.Slider_conc.Value;
            app.conc.Value=value;
        end

        % Callback function
        function Slider_concValueChanging(app, event)
            changingValue = event.Value; %#ok<NASGU> 
        end

        % Value changed function: conc
        function concValueChanged(app, event)
            value = app.conc.Value; %#ok<NASGU> 
            %app.Slider_conc.Limits=[0 10^floor(log10(value)+1)];
            %app.Slider_conc.Value=value;
        end

        % Callback function
        function Slider_exctValueChanged(app, event)
            value = app.Slider_exct.Value;
            app.exct.Value=value;
        end

        % Callback function
        function Slider_exctValueChanging(app, event)
            changingValue = event.Value; %#ok<NASGU> 
        end

        % Value changed function: exct
        function exctValueChanged(app, event)
            %value = app.exct.Value;
            %app.Slider_exct.Value=value;
            value = app.exct.Value; %#ok<NASGU> 
            %app.Slider_exct.Limits=[0 10^floor(log10(value)+1)];
            %app.Slider_exct.Value=value;
        end

        % Value changed function: path_gro
        function path_groValueChanged(app, event)
            %value = app.path_gro.Value;
            %while isempty(app.path_gro.Value{end})||strcmp(' ',app.path_gro.Value{end}(1))
            %    app.path_gro.Value=app.path_gro.Value(1:end-1);
            %end
        end

        % Value changed function: path_pro
        function path_proValueChanged(app, event)
            %value = app.path_pro.Value;
            %while isempty(app.path_pro.Value{end})||strcmp(' ',app.path_pro.Value{end}(1))
            %    app.path_pro.Value=app.path_pro.Value(1:end-1);
            %end
        end

        % Callback function
        function Slider_hourValueChanged(app, event)
            value = app.Slider_hour.Value;
            app.hour.Value=value;
        end

        % Callback function
        function Slider_fluxValueChanged(app, event)
            value = app.Slider_flux.Value;
            app.photons_per_pulse.Value=value;
        end

        % Callback function
        function Slider_repeValueChanged(app, event)
            value = app.Slider_repe.Value;
            app.pulse_repetition.Value=value;
        end

        % Callback function
        function Slider_paraValueChanged(app, event)
            value = app.Slider_para.Value;
            app.para.Value=value;
        end

        % Callback function
        function Slider_acculValueChanged(app, event)
            value = app.Slider_accul.Value;
            app.exposure_time.Value=value;
        end

        % Value changed function: hour
        function hourValueChanged(app, event)
            value = app.hour.Value; %#ok<NASGU> 
            %app.Slider_hour.Limits=[0 10^floor(log10(value)+1)];
            %app.Slider_hour.Value=value;
        end

        % Value changed function: photons_per_pulse
        function photons_per_pulseValueChanged(app, event)
            value = app.photons_per_pulse.Value; %#ok<NASGU> 
            %app.Slider_flux.Limits=[1 10^floor(log10(value)+1)];
            %app.Slider_flux.Value=value;
        end

        % Value changed function: pulse_repetition
        function pulse_repetitionValueChanged(app, event)
            value = app.pulse_repetition.Value; %#ok<NASGU> 
            %app.Slider_repe.Limits=[1 10^floor(log10(value)+1)];
            %app.Slider_repe.Value=value;
        end

        % Value changed function: para
        function paraValueChanged(app, event)
            value = app.para.Value; %#ok<NASGU> 
            %if 10^floor(log10(value)+1)<=1
            %    app.Slider_para.Limits=[0 10^floor(log10(value)+1)];
            %end
            %app.Slider_para.Value=value;
        end

        % Value changed function: exposure_time
        function exposure_timeValueChanged(app, event)
            value = app.exposure_time.Value; %#ok<NASGU> 
            %app.Slider_accul.Limits=[1 10^floor(log10(value)+1)];
            %app.Slider_accul.Value=value;
        end

        % Button pushed function: Save
        function SaveButtonPushed(app, event)
            time=datestr(now,'yymmdd_HHMMSS');
            path=uigetdir(pwd,'Select a directory to save the configuration files');
            writeconfig(app,time,path);
            path_file=sprintf('%s/Scube_config_%s.dat',path,time);
            set(app.Conf_files_name,'Value',path_file);
            %path=cd;
            
            %list=dir('config/*.dat');
            
            %             time_file=datestr(now,'yymmdd_HHMMSS');
            %             name_file=sprintf('/config/Scube_config_%s.dat',time_file);
            %             ID_file=fopen(name_file,'w');
            %
            %             fprintf(ID_file,'num_ground_coord\n');
            %             temp=sprintf('%d\n\n',length(app.path_gro.Value));
            %             fprintf(ID_file,temp);
            %
            %             fprintf(ID_file,'num_product_coord\n');
            %             temp=sprintf('%d\n\n',length(app.path_pro.Value));
            %             fprintf(ID_file,temp);
            %
            %             fprintf(ID_file,'path_ground_coord\n');
            %
            %             for ii=1:length(app.path_gro.Value)
            %                 temp=sprintf('%s\n',app.path_gro.Value{ii});
            %                 fprintf(ID_file,temp);
            %             end
            %             fprintf(ID_file,'\n');
            %
            %             fprintf(ID_file,'path_product_coord\n');
            %
            %             for ii=1:length(app.path_pro.Value)
            %                 temp=sprintf('%s\n',app.path_pro.Value{ii});
            %                 fprintf(ID_file,temp);
            %             end
            %             fprintf(ID_file,'\n');
            %
            %             fprintf(ID_file,'Solvent\n');
            %             temp=sprintf('%s\n\n',app.Solvent.Value);
            %             fprintf(ID_file,temp);
            %
            %             fprintf(ID_file,'Concentration\n');
            %             temp=sprintf('%.4g\n\n',app.conc.Value);
            %             fprintf(ID_file,temp);
            %
            %             fprintf(ID_file,'Quantum_yield\n');
            %             temp=sprintf('%.4g\n\n',app.exct.Value);
            %             fprintf(ID_file,temp);
            %
            %             fprintf(ID_file,'Wavelength_of_pump_laser\n');
            %             temp=sprintf('%.4g\n\n',app.wave.Value);
            %             fprintf(ID_file,temp);
            %
            %             fprintf(ID_file,'Accumulation_time(hours)\n');
            %             temp=sprintf('%.4g\n\n',app.hour.Value);
            %             fprintf(ID_file,temp);
            %
            %             fprintf(ID_file,'Photon_flux(Number_of_photons/shot)\n');
            %             temp=sprintf('%d\n\n',app.photons_per_pulse.Value);
            %             fprintf(ID_file,temp);
            %
            %             fprintf(ID_file,'Repetition_Rate\n');
            %             temp=sprintf('%d\n\n',app.pulse_repetition.Value);
            %             fprintf(ID_file,temp);
            %
            %             fprintf(ID_file,'Vitual_accumulation_number\n');
            %             temp=sprintf('%d\n\n',app.exposure_time.Value);
            %             fprintf(ID_file,temp);
            %
            %             fprintf(ID_file,'Practice_parameter\n');
            %             temp=sprintf('%.4g\n\n',app.para.Value);
            %             fprintf(ID_file,temp);
            %
            %
            %
            %             fclose(ID_file);
        end

        % Button pushed function: Load
        function LoadButtonPushed(app, event)
            
            [file, path] = uigetfile({'*.dat;','Config file (*.dat)';'*.*',  'All Files (*.*)'},'Select the config file');
            if isequal(file,0)
            else
                readconfig(app,path,file);
            end
            path_file=sprintf('%s/%s',path,file);
            set(app.Conf_files_name,'Value',path_file);
            
        end

        % Button pushed function: Simulate
        function SimulateButtonPushed(app, event)
            
            work_dirValueChanged(app);
            
            time=datestr(now,'yymmdd_HHMMSS');
            
            fprintf('               Reading input data initiate.               \n');
            applyconfig(app);
            Solvent_switch(app);
            get_exp_solvent_data(app);
            maximum_q_coorection(app);
            basic_declare(app);
            read_coord(app);
            read_fraction(app);
            fprintf('               Reading input data terminated.             \n');
            fprintf('----------------------------------------------------------\n');
            
            fprintf('           Calculating debye equation initiate.           \n');
            debye_solver(app);
            fprintf('          Calculating debye equation terminated.          \n');
            fprintf('----------------------------------------------------------\n');
            
            fprintf('      Calculating solvent and cage signal initiate.       \n');
            solvent_heating(app);
            Cage_cal(app);
            fprintf('     Calculating solvent and cage signal terminated.      \n');
            fprintf('----------------------------------------------------------\n');
            
            fprintf('                    Simulation initiate.                  \n');
            simulation(app);
            merge_data(app);
            fprintf('                   Simulation terminated.                 \n');
            fprintf('----------------------------------------------------------\n');
            
            fprintf('                      Plotting figure                     \n');
            drawdata(app);
            if app.getdir_check.Value==1
                writeoutput(app,time,app.work_dir.Value);
            end
            fprintf('                   Writing output files.                  \n');
            fprintf('                S-cube simulation completed.              \n');
            fprintf('----------------------------------------------------------\n');
        end

        % Value changed function: getdir_check
        function getdir_checkValueChanged(app, event)
%             value = app.getdir_check.Value;
%             if value == 1
%                 app.work_dir.Enable=1;
%                 app.getdir.Enable=1;
%             elseif value == 0
%                 app.work_dir.Enable=0;
%                 app.getdir.Enable=0;
%             end
        end

        % Button pushed function: getdir
        function getdirButtonPushed(app, event)
            dir_temp=uigetdir;
            if isequal(dir_temp,0)
            else
                app.work_dir.Value=dir_temp;
                work_dirValueChanged(app);
            end
        end

        % Button pushed function: draw
        function drawButtonPushed(app, event)
%             applyconfig(app);
%             Solvent_switch(app);
%             basic_declare(app);
%             read_coord(app);
%             
%             for ii=1:length(app.config.coord_gro)
%                 figure
%                 molecule3D(app.config.coord_gro{ii}.data,app.config.coord_gro{ii}.textdata);
%             end
%             
%             for ii=1:length(app.config.coord_pro)
%                 figure
%                 molecule3D(app.config.coord_pro{ii}.data,app.config.coord_pro{ii}.textdata);
%             end
        end

        % Button pushed function: get_noise
        function get_noisePushed(app, event)
            [file, path] = uigetfile({'*.mat;','Solvent experimental data (*.mat)';'*.*',  'All Files (*.*)'},'Select the solvent experimental data');
            if isequal(file,0)
            else
                app.noise_text.Value=sprintf('%s/%s',path,file);
            end
        end

        % Button pushed function: Getreactant
        function GetreactantButtonPushed(app, event)
            [file,path] = uigetfile({'*.txt;*.dat','XYZ format file(*.txt,*.dat)'},...
                'Select One or More coordination files', ...
                'MultiSelect', 'on');
            
            if isequal(file,0)
            elseif iscell(file)
                for ii=1:length(file)
                    app.path_gro.Value{ii}=sprintf('%s%s',path,file{ii});
                end
            else
                app.path_gro.Value{1}=sprintf('%s%s',path,file);
            end
        end

        % Button pushed function: Getproduct
        function GetproductButtonPushed(app, event)
            [file,path] = uigetfile({'*.txt;*.dat','XYZ format file(*.txt,*.dat)'},...
                'Select One or More coordination files', ...
                'MultiSelect', 'on');
            
            if isequal(file,0)
            elseif iscell(file)
                for ii=1:length(file)
                    app.path_pro.Value{ii}=sprintf('%s%s',path,file{ii});
                end
            else
                app.path_pro.Value{1}=sprintf('%s%s',path,file);
            end
        end

        % Value changed function: dt_switch
        function dt_switchValueChanged(app, event)
            value = app.dt_switch.Value; %#ok<NASGU> 
            if strcmp(app.dt_switch.Value,'Off') && strcmp(app.drho_switch.Value,'Off')
                app.wave.Enable=0;
            else
                app.wave.Enable=1;
            end
        end

        % Value changed function: drho_switch
        function drho_switchValueChanged(app, event)
            value = app.drho_switch.Value; %#ok<NASGU> 
            if strcmp(app.dt_switch.Value,'Off') && strcmp(app.drho_switch.Value,'Off')
                app.wave.Enable=0;
            else
                app.wave.Enable=1;
            end
        end

        % Value changed function: cage_knob
        function cage_knobValueChanged(app, event)
            value = app.cage_knob.Value;
            if strcmp(value,'MD simulations')
                set(app.cage_name,'Visible',1);
                set(app.get_cage_name,'Visible',1);
            else
                set(app.cage_name,'Visible',0);
                set(app.get_cage_name,'Visible',0);
            end
        end

        % Button pushed function: get_cage_name
        function get_cage_nameButtonPushed(app, event)
            [file, path] = uigetfile({'*.dat;','Cage file (*.dat)';'*.*',  'All Files (*.*)'},'Select the cage file');
            path_file=sprintf('%s/%s',path,file);
            set(app.cage_name,'Value',path_file);
        end

        % Button pushed function: Simulate_batch
        function Simulate_batchButtonPushed(app, event)
            dir_temp=uigetdir;
            set(app.work_dir.Value,dir_temp);
            work_dirValueChanged(app);
            file_list_wildcard=sprintf('%s/Scube_config_*.dat',dir_temp);
            file_list=dir(file_list_wildcard);
            for index_file=1:length(file_list)
                readconfig(app,file_list(index_file).folder,file_list(index_file).name)

                time=datestr(now,'yymmdd_HHMMSS');
                applyconfig(app);
                Solvent_switch(app);
                get_exp_solvent_data(app);
                basic_declare(app);
                read_coord(app);
                read_fraction(app);
                debye_solver(app);
                solvent_heating(app);
                Cage_cal(app);
                
                simulation(app);
                merge_data(app);
                drawdata(app);
                if app.getdir_check.Value==1
                    writeoutput(app,time,app.work_dir.Value);
                end
            end
        end

        % Value changed function: sample_distance
        function sample_distanceValueChanged(app, event)
            value = app.sample_distance.Value; %#ok<NASGU> 
        end

        % Value changed function: work_dir
        function work_dirValueChanged(app, event)
            value = app.work_dir.Value;
            try
                cd(value);
            catch
                error('Working Directory name Error !!!');
            end
            fprintf('       Wokring Directory : %s\n',pwd);
            fprintf('---------------------------------------------------------\n');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.main = uifigure('Visible', 'off');
            app.main.Name = 'Scube';
            app.Solventpart = uipanel(app.main);
            app.Solventpart.Title = 'Solvent Selection';
            app.SolventDropDownLabel = uilabel(app.Solventpart);
            app.SolventDropDownLabel.Text = 'Solvent';
            app.Solvent = uidropdown(app.Solventpart);
            app.Solvent.Items = {'Acetonitrile(CH3CN)', 'Water(H2O)', 'Methanol(CH3OH)', 'Cyclohexane(C6H12)', 'Custom'};
            app.Solvent.Value = 'Cyclohexane(C6H12)';
            app.Solutepart = uipanel(app.main);
            app.Solutepart.Title = 'The solute-only term';
            app.MoleculargeometryfilesLabel = uilabel(app.Solutepart);
            app.MoleculargeometryfilesLabel.Text = {'Molecular '; 'geometry files'};
            app.path_gro = uitextarea(app.Solutepart);
            app.path_gro.ValueChangedFcn = createCallbackFcn(app, @path_groValueChanged, true);
            app.path_gro.Value = {'azobenzene\AB_t.txt'};
            app.ConcentrationofsolutemMLabel = uilabel(app.Solutepart);
            app.ConcentrationofsolutemMLabel.Text = 'Concentration of solute(mM)';
            app.conc = uieditfield(app.Solutepart, 'numeric');
            app.conc.Limits = [0 Inf];
            app.conc.ValueChangedFcn = createCallbackFcn(app, @concValueChanged, true);
            app.conc.Value = 20;
            app.Excitationratiostructurallychangedsolutetotalsolute1Label = uilabel(app.Solutepart);
            app.Excitationratiostructurallychangedsolutetotalsolute1Label.Text = {'Excitation ratio '; '(structurally changed '; 'solute/total solute) (<1)'};
            app.exct = uieditfield(app.Solutepart, 'numeric');
            app.exct.Limits = [0 1];
            app.exct.ValueChangedFcn = createCallbackFcn(app, @exctValueChanged, true);
            app.exct.Value = 0.1;
            app.MoleculargeometryfilesLabel_2 = uilabel(app.Solutepart);
            app.MoleculargeometryfilesLabel_2.Text = {'Molecular '; 'geometry files'};
            app.path_pro = uitextarea(app.Solutepart);
            app.path_pro.ValueChangedFcn = createCallbackFcn(app, @path_proValueChanged, true);
            app.path_pro.Value = {'azobenzene\AB_c.txt'};
            app.draw = uibutton(app.Solutepart, 'push');
            app.draw.ButtonPushedFcn = createCallbackFcn(app, @drawButtonPushed, true);
            app.draw.Enable = 'off';
            app.draw.Visible = 'off';
            app.draw.Text = 'Draw the molecular geometry files';
            app.Getreactant = uibutton(app.Solutepart, 'push');
            app.Getreactant.ButtonPushedFcn = createCallbackFcn(app, @GetreactantButtonPushed, true);
            app.Getreactant.Text = 'Get geom.';
            app.Getproduct = uibutton(app.Solutepart, 'push');
            app.Getproduct.ButtonPushedFcn = createCallbackFcn(app, @GetproductButtonPushed, true);
            app.Getproduct.Text = 'Get geom.';
            app.FractionLabel = uilabel(app.Solutepart);
            app.FractionLabel.Text = 'Fraction';
            app.fraction_gro = uieditfield(app.Solutepart, 'text');
            app.fraction_gro.Value = '1';
            app.FractionLabel_2 = uilabel(app.Solutepart);
            app.FractionLabel_2.Text = 'Fraction';
            app.fraction_pro = uieditfield(app.Solutepart, 'text');
            app.fraction_pro.Value = '1';
            app.ReactantGroundstateLabel = uilabel(app.Solutepart);
            app.ReactantGroundstateLabel.Text = 'Reactant / Ground state';
            app.ProductIntermediateLabel = uilabel(app.Solutepart);
            app.ProductIntermediateLabel.Text = 'Product / Intermediate';
            app.Thermalexcitationratiostructuralchangedsolutetotalsolute1Label = uilabel(app.Solutepart);
            app.Thermalexcitationratiostructuralchangedsolutetotalsolute1Label.Text = {'Thermal excitation ratio '; '(Only thermally excited '; 'solute/total solute) (<1)'};
            app.vibcool = uieditfield(app.Solutepart, 'numeric');
            app.vibcool.Limits = [0 1];
            app.vibcool.Value = 0.1;
            app.Heatingpart = uipanel(app.main);
            app.Heatingpart.Title = 'The solvent-only term';
            app.WavelengthofpumplasernmEditFieldLabel = uilabel(app.Heatingpart);
            app.WavelengthofpumplasernmEditFieldLabel.Text = 'Wavelength of pump laser(nm)';
            app.wave = uieditfield(app.Heatingpart, 'numeric');
            app.wave.Limits = [0 Inf];
            app.wave.Value = 315;
            app.dSdTpsSwitchLabel = uilabel(app.Heatingpart);
            app.dSdTpsSwitchLabel.Text = 'dS/dT(ps)';
            app.dt_switch = uiswitch(app.Heatingpart, 'toggle');
            app.dt_switch.ValueChangedFcn = createCallbackFcn(app, @dt_switchValueChanged, true);
            app.dt_switch.Value = 'On';
            app.dSdrhonsSwitchLabel = uilabel(app.Heatingpart);
            app.dSdrhonsSwitchLabel.Text = 'dS/drho(ns)';
            app.drho_switch = uiswitch(app.Heatingpart, 'toggle');
            app.drho_switch.ValueChangedFcn = createCallbackFcn(app, @drho_switchValueChanged, true);
            app.drho_switch.Value = 'On';
            app.Simulationpart = uipanel(app.main);
            app.Simulationpart.Title = 'The noise term - Simulation conditions';
            app.CollectiontimeshoursLabel = uilabel(app.Simulationpart);
            app.CollectiontimeshoursLabel.Text = 'Collection times (hours)';
            app.hour = uieditfield(app.Simulationpart, 'numeric');
            app.hour.Limits = [0 Inf];
            app.hour.ValueChangedFcn = createCallbackFcn(app, @hourValueChanged, true);
            app.hour.Value = 1;
            app.PhotonspulseLabel = uilabel(app.Simulationpart);
            app.PhotonspulseLabel.Text = 'Photons/pulse';
            app.photons_per_pulse = uieditfield(app.Simulationpart, 'numeric');
            app.photons_per_pulse.Limits = [0 Inf];
            app.photons_per_pulse.RoundFractionalValues = 'on';
            app.photons_per_pulse.ValueChangedFcn = createCallbackFcn(app, @photons_per_pulseValueChanged, true);
            app.photons_per_pulse.Value = 1044932079;
            app.PulserepetitionrateHzLabel = uilabel(app.Simulationpart);
            app.PulserepetitionrateHzLabel.Text = {'Pulse repetition'; ' rate (Hz)'};
            app.pulse_repetition = uieditfield(app.Simulationpart, 'numeric');
            app.pulse_repetition.Limits = [0 Inf];
            app.pulse_repetition.ValueChangedFcn = createCallbackFcn(app, @pulse_repetitionValueChanged, true);
            app.pulse_repetition.Value = 957;
            app.DutycycleLabel = uilabel(app.Simulationpart);
            app.DutycycleLabel.Text = 'Duty cycle';
            app.para = uieditfield(app.Simulationpart, 'numeric');
            app.para.Limits = [0 1];
            app.para.ValueChangedFcn = createCallbackFcn(app, @paraValueChanged, true);
            app.para.Value = 1;
            app.ExposuretimessLabel = uilabel(app.Simulationpart);
            app.ExposuretimessLabel.Text = 'Exposure times (s)';
            app.exposure_time = uieditfield(app.Simulationpart, 'numeric');
            app.exposure_time.Limits = [0 Inf];
            app.exposure_time.ValueChangedFcn = createCallbackFcn(app, @exposure_timeValueChanged, true);
            app.exposure_time.Value = 2;
            app.SampletodetectordistancemmLabel = uilabel(app.Simulationpart);
            app.SampletodetectordistancemmLabel.Text = {'Sample-to-detector'; 'distance(mm)'};
            app.sample_distance = uieditfield(app.Simulationpart, 'numeric');
            app.sample_distance.Limits = [0 Inf];
            app.sample_distance.ValueChangedFcn = createCallbackFcn(app, @sample_distanceValueChanged, true);
            app.sample_distance.Value = 29.523;
            app.save = uipanel(app.main);
            app.save.Title = 'Working Directory';
            app.work_dir = uieditfield(app.save, 'text');
            app.work_dir.ValueChangedFcn = createCallbackFcn(app, @work_dirValueChanged, true);
            app.work_dir.Value = 'C:\Users\Jungmin_PC\Desktop\Azo_DiBr_AZo\Scube_simulation';
            app.getdir = uibutton(app.save, 'push');
            app.getdir.ButtonPushedFcn = createCallbackFcn(app, @getdirButtonPushed, true);
            app.getdir.Text = 'Get directory';
            app.getdir_check = uicheckbox(app.save);
            app.getdir_check.ValueChangedFcn = createCallbackFcn(app, @getdir_checkValueChanged, true);
            app.getdir_check.Text = 'Make results data';
            app.getdir_check.Value = true;
            app.NoisepartPanel = uipanel(app.main);
            app.NoisepartPanel.Title = 'The noise term - Standard deviation of solvet scattering intensity files Selection';
            app.noise_text = uieditfield(app.NoisepartPanel, 'text');
            app.noise_text.Value = 'cyclohexane_kek_1912_200130.mat';
            app.get_noise = uibutton(app.NoisepartPanel, 'push');
            app.get_noise.ButtonPushedFcn = createCallbackFcn(app, @get_noisePushed, true);
            app.get_noise.Text = 'Get noise files';
            app.ConfigurationsFilesPanel = uipanel(app.main);
            app.ConfigurationsFilesPanel.Title = 'Configurations Files';
            app.Conf_files_name = uitextarea(app.ConfigurationsFilesPanel);
            app.Conf_files_name.Editable = 'off';
            app.Load = uibutton(app.ConfigurationsFilesPanel, 'push');
            app.Load.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.Load.Text = 'Load config';
            app.Save = uibutton(app.ConfigurationsFilesPanel, 'push');
            app.Save.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.Save.Text = 'Save config';
            app.Simulate = uibutton(app.main, 'push');
            app.Simulate.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.Simulate.Text = 'Simulate';
            app.ThecageSoluteSolventcrosstermPanel = uipanel(app.main);
            app.ThecageSoluteSolventcrosstermPanel.Title = 'The cage (Solute-Solvent cross) term ';
            app.CagefilesnameLabel = uilabel(app.ThecageSoluteSolventcrosstermPanel);
            app.CagefilesnameLabel.Text = 'Cage files name';
            app.cage_name = uieditfield(app.ThecageSoluteSolventcrosstermPanel, 'text');
            app.cage_name.Visible = 'off';
            app.cage_name.Value = 'azobenzene/cage_Azo_tS0_cS0.dat';
            app.CageprocessmethodKnobLabel = uilabel(app.ThecageSoluteSolventcrosstermPanel);
            app.CageprocessmethodKnobLabel.Text = 'Cage process method';
            app.cage_knob = uiknob(app.ThecageSoluteSolventcrosstermPanel, 'discrete');
            app.cage_knob.Items = {'None', 'Hard-spheres', 'MD simulations'};
            app.cage_knob.ValueChangedFcn = createCallbackFcn(app, @cage_knobValueChanged, true);
            app.cage_knob.Value = 'Hard-spheres';
            app.get_cage_name = uibutton(app.ThecageSoluteSolventcrosstermPanel, 'push');
            app.get_cage_name.ButtonPushedFcn = createCallbackFcn(app, @get_cage_nameButtonPushed, true);
            app.get_cage_name.Visible = 'off';
            app.get_cage_name.Text = 'Get Cage files';
            app.Simulate_batch = uibutton(app.main, 'push');
            app.Simulate_batch.ButtonPushedFcn = createCallbackFcn(app, @Simulate_batchButtonPushed, true);
            app.Simulate_batch.Text = {'Simulate '; '(Batch process)'};
            % Show the figure after all components are created
            app.main.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Scube

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.main)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.main)
        end
    end
end